classdef CJ_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdateButton                  matlab.ui.control.Button
        CountryDropDown               matlab.ui.control.DropDown
        HostCityEditFieldLabel        matlab.ui.control.Label
        HostCity                      matlab.ui.control.EditField
        YearButtonGroup_7             matlab.ui.container.ButtonGroup
        AllButton                     matlab.ui.control.RadioButton
        Button_35                     matlab.ui.control.RadioButton
        Button_34                     matlab.ui.control.RadioButton
        Button_33                     matlab.ui.control.RadioButton
        Button_32                     matlab.ui.control.RadioButton
        Button_31                     matlab.ui.control.RadioButton
        TabGroup                      matlab.ui.container.TabGroup
        CountryTab                    matlab.ui.container.Tab
        UpdateListButton              matlab.ui.control.Button
        Table                         matlab.ui.control.Table
        UIAxes_5                      matlab.ui.control.UIAxes
        MedalsTab                     matlab.ui.container.Tab
        MedalDist                     matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        MeasurementsTab               matlab.ui.container.Tab
        ButtonGroup                   matlab.ui.container.ButtonGroup
        WeightButton                  matlab.ui.control.ToggleButton
        HeightButton                  matlab.ui.control.ToggleButton
        Histogram                     matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        GenderTab                     matlab.ui.container.Tab
        Female                        matlab.ui.control.NumericEditField
        FemaleAthletesEditFieldLabel  matlab.ui.control.Label
        Male                          matlab.ui.control.NumericEditField
        MaleAthletesLabel             matlab.ui.control.Label
        PieChart                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        Title                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            olympicsCJ(app)
            file2 = imread('pictreu 2.png');
            imshow(file2,'Parent',app.UIAxes)
            file = imread('pic.jpg');
            imshow(file,'Parent',app.UIAxes_5)
            axis(app.UIAxes_5, 'tight')
            imshow(file,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2, 'tight')
            imshow(file,'Parent',app.UIAxes_3)
            axis(app.UIAxes_3, 'tight')
            imshow(file,'Parent',app.UIAxes_4)
            axis(app.UIAxes_4, 'tight')
            OlympicsList(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            olympicsCJ(app)
        end

        % Selection changed function: YearButtonGroup_7
        function YearButtonGroup_7SelectionChanged(app, event)
            button = app.YearButtonGroup_7.SelectedObject.Text;

        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
            value = app.CountryDropDown.Value;
            
        end

        % Callback function: ButtonGroup, Histogram
        function ButtonGroupSelectionChanged(app, event)
            measurement = app.ButtonGroup.SelectedObject.Text;
            
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            OlympicsList(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = ' OLYMPIC SNOWBOARDER DATA (1998-2014)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CountryTab = uitab(app.TabGroup);
            app.CountryTab.Title = 'Country';
            app.UIAxes_5 = uiaxes(app.CountryTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.Table = uitable(app.CountryTab);
            app.Table.ColumnName = {'Name'; 'Country'; 'Gender'; 'Medal'};
            app.Table.RowName = {};
            app.UpdateListButton = uibutton(app.CountryTab, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.MedalsTab = uitab(app.TabGroup);
            app.MedalsTab.Title = 'Medals';
            app.UIAxes_2 = uiaxes(app.MedalsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.MedalDist = uiaxes(app.MedalsTab);
            title(app.MedalDist, 'Medal Distribution')
            ylabel(app.MedalDist, 'Numer of Medals')
            zlabel(app.MedalDist, 'Z')
            app.MeasurementsTab = uitab(app.TabGroup);
            app.MeasurementsTab.Title = 'Measurements';
            app.UIAxes_3 = uiaxes(app.MeasurementsTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Histogram = uiaxes(app.MeasurementsTab);
            title(app.Histogram, 'Athlete Height or Weight')
            ylabel(app.Histogram, 'Number of Athletes')
            zlabel(app.Histogram, 'Z')
            app.Histogram.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup = uibuttongroup(app.MeasurementsTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HeightButton = uitogglebutton(app.ButtonGroup);
            app.HeightButton.Text = 'Height';
            app.HeightButton.Value = true;
            app.WeightButton = uitogglebutton(app.ButtonGroup);
            app.WeightButton.Text = 'Weight';
            app.GenderTab = uitab(app.TabGroup);
            app.GenderTab.Title = 'Gender %';
            app.UIAxes_4 = uiaxes(app.GenderTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.PieChart = uiaxes(app.GenderTab);
            title(app.PieChart, 'Percent of Male vs Female Athletes')
            xlabel(app.PieChart, 'X')
            ylabel(app.PieChart, 'Y')
            zlabel(app.PieChart, 'Z')
            app.MaleAthletesLabel = uilabel(app.GenderTab);
            app.MaleAthletesLabel.Text = 'Male Athletes';
            app.Male = uieditfield(app.GenderTab, 'numeric');
            app.FemaleAthletesEditFieldLabel = uilabel(app.GenderTab);
            app.FemaleAthletesEditFieldLabel.Text = 'Female Athletes';
            app.Female = uieditfield(app.GenderTab, 'numeric');
            app.YearButtonGroup_7 = uibuttongroup(app.UIFigure);
            app.YearButtonGroup_7.SelectionChangedFcn = createCallbackFcn(app, @YearButtonGroup_7SelectionChanged, true);
            app.YearButtonGroup_7.Title = 'Year';
            app.Button_31 = uiradiobutton(app.YearButtonGroup_7);
            app.Button_31.Text = '1998';
            app.Button_31.Value = true;
            app.Button_32 = uiradiobutton(app.YearButtonGroup_7);
            app.Button_32.Text = '2002';
            app.Button_33 = uiradiobutton(app.YearButtonGroup_7);
            app.Button_33.Text = '2006';
            app.Button_34 = uiradiobutton(app.YearButtonGroup_7);
            app.Button_34.Text = '2010';
            app.Button_35 = uiradiobutton(app.YearButtonGroup_7);
            app.Button_35.Text = '2014';
            app.AllButton = uiradiobutton(app.YearButtonGroup_7);
            app.AllButton.Text = 'All';
            app.HostCity = uieditfield(app.UIFigure, 'text');
            app.HostCityEditFieldLabel = uilabel(app.UIFigure);
            app.HostCityEditFieldLabel.Text = 'Host City';
            app.CountryDropDown = uidropdown(app.UIFigure);
            app.CountryDropDown.Items = {'All', 'Andorra', 'Argentina', 'Australia', 'Austria', 'Belgium', 'Brazil', 'Bulgaria', 'Canada', 'China', 'Croatia', 'Czech Republic', 'Denmark', 'Finland', 'France', 'Germany', 'Great Britain', 'Greece', 'Ireland', 'Italy', 'Japan', 'Kazakhstan', 'Netherlands', 'New Zealand', 'Norway', 'Poland', 'Russia', 'Serbia', 'Slovenia', 'South Korea', 'Spain', 'Sweden', 'Switzerland', 'Ukraine', 'United States', ''};
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.CountryDropDown.Value = 'All';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CJ_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end